classdef Newmodel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ParametersettingsTab     matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        T_0SliderLabel           matlab.ui.control.Label
        T_0Slider                matlab.ui.control.Slider
        AR_OrderDropDownLabel    matlab.ui.control.Label
        AR_OrderDropDown         matlab.ui.control.DropDown
        Lag_ordersDropDownLabel  matlab.ui.control.Label
        Lag_ordersDropDown       matlab.ui.control.DropDown
        ImportDataButton         matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        l1SliderLabel            matlab.ui.control.Label
        l1Slider                 matlab.ui.control.Slider
        UIAxes4                  matlab.ui.control.UIAxes
        l2SliderLabel            matlab.ui.control.Label
        l2Slider                 matlab.ui.control.Slider
        Step4LampLabel           matlab.ui.control.Label
        Step4LampLabel_2         matlab.ui.control.Label
        Step4LampLabel_3         matlab.ui.control.Label
        Step4LampLabel_4         matlab.ui.control.Label
        Step4LampLabel_5         matlab.ui.control.Label
        Step4LampLabel_6         matlab.ui.control.Label
        Step4LampLabel_7         matlab.ui.control.Label
        ResultoutputTab          matlab.ui.container.Tab
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes3_2                matlab.ui.control.UIAxes
        UIAxes3_3                matlab.ui.control.UIAxes
        UIAxes3_4                matlab.ui.control.UIAxes
        OutputresultButton       matlab.ui.control.Button
    end

    
% Private properties
    properties (Access = private)
        DataTable table  % 保存时间序列数据的表
        % 定义私有属性
    Time_series_predict  % 时间序列预测数据
    LagEffect            % 滞后效应
    Beta_LagTerm         % 滞后项系数
%         Time_point_series  % 共享变量
        
    end
    
    methods (Access = private)
        
        function [Structure_out,Structure]=Lag_DF_app(app,T_0,T_e,Advance_length,Lag_length)
        
        T_b=0;% 时间范围开始点 基本上没有变化
        T_1=T_0-Advance_length;% Initial change point 初始变化点 begin_point
        L=Advance_length+Lag_length;% 干预效应持续时间长度 %lag_rang
        %% 提取PDF的权重结构
        [x_axis,PDFF_y_U,DF_y_U]=Uniform_distribution(L,T_1,T_b,T_e);
        [x_axis,PDFF_y_ND,DF_y_ND]=Normal_distribution(L,T_1,T_b,T_e);
        [x_axis,PDFF_y_LND,DF_y_LND]=logarithmic_normal_distribution(L,T_1,T_b,T_e);
        [x_axis,PDFF_y_LNFD,DF_y_LNFD]=logarithmic_normal_distribution_Flip(L,T_1,T_b,T_e);
        %% 输出序列
        Structure=[x_axis,PDFF_y_U,PDFF_y_ND,PDFF_y_LND,PDFF_y_LNFD,DF_y_U,DF_y_ND,DF_y_LND,DF_y_LNFD];
        Structure_out=[];count=1;
        for i=1:length(x_axis)
           if x_axis(i)==count
             Structure_out=[Structure_out;Structure(i,:)];
             count=count+1;
           else
           end
        end
        
        cla(app.UIAxes4);  % 清除指定坐标轴 
        % 设置统一的最大 Y 轴值
    P_y = 1.2 * max(max(Structure_out(:,2:4))); 
         
    % 绘制第一个区域图 (Structure(:,2))
    ar = area(app.UIAxes4, Structure(:, 1), Structure(:, 2));
    ar.EdgeColor = 'r';  % 红色边框
    ar.FaceColor = 'r';  % 红色填充
%     ar.LineWidth = 1;    % 边框线宽
    ar.FaceAlpha = 0.3;  % 透明度
    hold(app.UIAxes4, 'on'); % 保持当前绘图

    % 绘制第二个区域图 (Structure(:,3))
    ar = area(app.UIAxes4, Structure(:, 1), Structure(:, 3));
    ar.EdgeColor = 'm';  % 品红色边框
    ar.FaceColor = 'm';  % 品红色填充
%     ar.LineWidth = 1;
    ar.FaceAlpha = 0.3;
    hold(app.UIAxes4, 'on');

    % 绘制第三个区域图 (Structure(:,4))
    ar = area(app.UIAxes4, Structure(:, 1), Structure(:, 4));
    ar.EdgeColor = 'b';  % 蓝色边框
    ar.FaceColor = 'b';  % 蓝色填充
%     ar.LineWidth = 1;
    ar.FaceAlpha = 0.3;
    hold(app.UIAxes4, 'on');

    % 绘制第四个区域图 (Structure(:,5))
    ar = area(app.UIAxes4, Structure(:, 1), Structure(:, 5));
    ar.EdgeColor = 'g';  % 绿色边框
    ar.FaceColor = 'g';  % 绿色填充
%     ar.LineWidth = 1;
    ar.FaceAlpha = 0.3;
    hold(app.UIAxes4, 'on');

    plot(app.UIAxes4, [T_0, T_0], [0, P_y], 'k-.', 'LineWidth', 2.5);

    % 设置 X 和 Y 轴范围
    xlim(app.UIAxes4, [1, T_e]);
    ylim(app.UIAxes4, [0, P_y]);

    % 设置 X 轴刻度和标签
    set(app.UIAxes4, 'XTick', [T_1, T_0, T_1 + L, T_e], ...
                     'XTickLabel', {'T_1', 'T_0', 'T_2', 'T_e'});

    % 清理 hold 状态
    hold(app.UIAxes4, 'off');
        
            
        end


        function [Time_series_predict,LagEffect,Beta_LagTerm]=Parameter_estimation(app,T_0,T_e,L1,L2,Time_series,lag_term,AR_Order,s)
            
        X_sequence=(1:T_e);
        T_b=0;% 时间范围开始点 基本上没有变化
% T_e=30;% 时间范围结束点

%% 参数调节
       T_1=T_0-L1;% 首个柱子所对应的坐标
       L=L1+L2;% 干预效应持续时间长度 %lag_rang
       T_2=T_0+L2;

       lag_term_PD=lag_term((T_1+1):(T_2),:);
%%
       PD_U=lag_term_PD(:,2);
       PD_ND=lag_term_PD(:,3);
       PD_LND=lag_term_PD(:,4);
       PD_LNFD=lag_term_PD(:,5);
%%

% [Time_series_predict,Time_series_LagEffect,Beta_LagTerm]=Lag_effect_Indentification(app,T_1,L,T_2,T_e,PD_Inclusion,AR_Order,Time_series,T_0,plot_color,s)

      [Time_series_predict_U,LagEffect_U,Beta_LagTerm_U]=Lag_effect_Indentification(app,T_1,L,T_2,T_e,PD_U,AR_Order,Time_series,T_0,'r',s);

      
      % 绘制影响效应图
P_y = 1.2 * max(abs(LagEffect_U)); % 计算Y轴的最大范围
% 在 app.UIAxes2 中绘制纵向线条
bar(app.UIAxes3,LagEffect_U,'r');
hold(app.UIAxes3, 'on'); 
plot(app.UIAxes3, [T_0, T_0], [-P_y, P_y], 'k-.', 'linewidth', 2.5);
% 设置 X 轴刻度及标签
set(app.UIAxes3, 'XTick', [T_1, T_2, T_2 + s], ...
                 'XTickLabel', {'T_1', 'T_2', 'T_2+s'});
% 设置坐标轴范围
xlim(app.UIAxes3, [T_b, T_e]);       % 设置 X 轴范围
ylim(app.UIAxes3, [-P_y, P_y]);      % 设置 Y 轴范围
hold(app.UIAxes3, 'off'); % 关闭对当前轴的保持


      [Time_series_predict_ND,LagEffect_ND,Beta_LagTerm_ND]=Lag_effect_Indentification(app,T_1,L,T_2,T_e,PD_ND,AR_Order,Time_series,T_0,'m',s);

            % 绘制影响效应图
P_y = 1.2 * max(abs(LagEffect_ND)); % 计算Y轴的最大范围
% 在 app.UIAxes2 中绘制纵向线条
bar(app.UIAxes3_2,LagEffect_ND,'m');
hold(app.UIAxes3_2, 'on'); 
plot(app.UIAxes3_2, [T_0, T_0], [-P_y, P_y], 'k-.', 'linewidth', 2.5);
% 设置 X 轴刻度及标签
set(app.UIAxes3_2, 'XTick', [T_1, T_2, T_2 + s], ...
                 'XTickLabel', {'T_1', 'T_2', 'T_2+s'});
% 设置坐标轴范围
xlim(app.UIAxes3_2, [T_b, T_e]);       % 设置 X 轴范围
ylim(app.UIAxes3_2, [-P_y, P_y]);      % 设置 Y 轴范围
hold(app.UIAxes3_2, 'off'); % 关闭对当前轴的保持


      [Time_series_predict_LND,LagEffect_LND,Beta_LagTerm_LND]=Lag_effect_Indentification(app,T_1,L,T_2,T_e,PD_LND,AR_Order,Time_series,T_0,'b',s);

                  % 绘制影响效应图
P_y = 1.2 * max(abs(LagEffect_LND)); % 计算Y轴的最大范围
% 在 app.UIAxes2 中绘制纵向线条
bar(app.UIAxes3_3,LagEffect_LND,'b');
hold(app.UIAxes3_3, 'on'); 
plot(app.UIAxes3_3, [T_0, T_0], [-P_y, P_y], 'k-.', 'linewidth', 2.5);
% 设置 X 轴刻度及标签
set(app.UIAxes3_3, 'XTick', [T_1, T_2, T_2 + s], ...
                 'XTickLabel', {'T_1', 'T_2', 'T_2+s'});
% 设置坐标轴范围
xlim(app.UIAxes3_3, [T_b, T_e]);       % 设置 X 轴范围
ylim(app.UIAxes3_3, [-P_y, P_y]);      % 设置 Y 轴范围
hold(app.UIAxes3_3, 'off'); % 关闭对当前轴的保持


      [Time_series_predict_LNFD,LagEffect_LNFD,Beta_LagTerm_LNFD]=Lag_effect_Indentification(app,T_1,L,T_2,T_e,PD_LNFD,AR_Order,Time_series,T_0,'g',s);

      % 绘制影响效应图
P_y = 1.2 * max(abs(LagEffect_LNFD)); % 计算Y轴的最大范围
% 在 app.UIAxes2 中绘制纵向线条
bar(app.UIAxes3_4,LagEffect_LNFD,'g');
hold(app.UIAxes3_4, 'on'); 
plot(app.UIAxes3_4, [T_0, T_0], [-P_y, P_y], 'k-.', 'linewidth', 2.5);
% 设置 X 轴刻度及标签
set(app.UIAxes3_4, 'XTick', [T_1, T_2, T_2 + s], ...
                 'XTickLabel', {'T_1', 'T_2', 'T_2+s'});
% 设置坐标轴范围
xlim(app.UIAxes3_4, [T_b, T_e]);       % 设置 X 轴范围
ylim(app.UIAxes3_4, [-P_y, P_y]);      % 设置 Y 轴范围
hold(app.UIAxes3_4, 'off'); % 关闭对当前轴的保持
      
    
%%
      LagEffect=[LagEffect_U,LagEffect_ND,LagEffect_LND,LagEffect_LNFD];
      Time_series_predict=[Time_series_predict_U Time_series_predict_ND Time_series_predict_LND Time_series_predict_LNFD];
      Beta_LagTerm=[Beta_LagTerm_U,Beta_LagTerm_ND,Beta_LagTerm_LND,Beta_LagTerm_LNFD]';


        end


        function [Time_series_predict,Time_series_LagEffect,Beta_LagTerm]=Lag_effect_Indentification(app,T_1,L,T_2,T_e,PD_Inclusion,AR_Order,Time_series,T_0,plot_color,s)
            
        T_b=0;

%         s=round(s);

%% P_1的构建
P_1=zeros(T_1,s);
%% P_2的构建，最关键的部分
P_2=zeros((L+s),s);
for i=1:s
    P_2(i:(i+L-1),i)=PD_Inclusion;
end
%%  P_3的构建
P_3=zeros(T_e-T_1-L-s,s);
%% 合并形成整个的矩阵P
P=[P_1;P_2; P_3];
%% AR_O ARIMA中AR部分阶数 
% Time_series
AR_part=zeros(T_e,AR_Order);

for i=1:T_e % 该部分的算法思维是横行填充
    for j=1:AR_Order
        if i-j<1
            continue;
        else
            AR_part(i,j)=Time_series(i-j);
        end
    end
end

%%
X=[AR_part P];%处理矩阵的多重共线性
%%

% % 求其特征值
det_XT_X=det((X')*X);%求方阵A所对应的行列式的值 2.19046378937688e-33
% VIF = SingleVIF(X) % MultipleVIF，PartialVIF，SingleVIF
I=eye(AR_Order+s);%(多重共线性), 即非满秩矩阵 https://zhuanlan.zhihu.com/p/30535220

ridge_trace_matrix=[];
X_axis_L=[];
determinant_matrix=[];
MSE =[];

for i=0.001:0.001:1
    
    alpha=i;
    
    Ridge_Return=X'*X+alpha*I; % 岭回归矩阵
    
    Beta=inv(Ridge_Return)*X'*Time_series;% 估计参数(Φ W )

    ridge_trace_matrix=[ridge_trace_matrix,Beta]; % 逐步估计，系数的估计值
    
    X_axis_L=[X_axis_L,i];
    
    determinant_matrix=[determinant_matrix det(Ridge_Return)]; % 逐步估计，岭回归矩阵的行列式的值
    
    if det((X')*X+alpha*I)>0.1 %其标准
        break;
    end
    
end


ARIMA_Beta=Beta(1:AR_Order); % 自回归AR的系数估计值
Beta_LagTerm=Beta((AR_Order+1):end); % 自回归AR的系数估计值

Time_series_LagEffect=P*Beta_LagTerm;

Time_series_predict=X*Beta;
% %%
% switch plot_color
%     case 'r'
%         % 当 expression 等于 value1 时执行
%         Distribution_pattern = ' Uniform Distribution';
%     case 'm'
%         % 当 expression 等于 value2 时执行
%         Distribution_pattern = ' Normal Distribution';
%     case 'b'
%         % 当 expression 等于 value2 时执行
%         Distribution_pattern = ' Log-normal Distribution';
%     otherwise
%         % 当 expression 不等于任何指定的值时执行
%         Distribution_pattern = ' Log-normal Flip Distribution';
% end
% 
% %% 画出其效应的估计值，以及 岭迹图，正文中的Fig4
% figure()% 岭迹图

% P_y =1.2*max(abs(Time_series_LagEffect)); %5为在2点的y值
% plot([T_0,T_0],[-P_y,P_y ],'k-.','linewidth',2.5);
% set(gca,'xtick',[T_1 T_2 T_2+s],'xticklabel',{'T_1' 'T_2' 'T_2+s'})
% xlim([T_b T_e])
% ylim([-P_y P_y])
% ylabel('Impact size');
% hold off
% title_name = strcat('Intervention Impacts for ');
% title(title_name)


%% 计算拟合值

Time_series_predict=X*Beta;
%%

y_test =Time_series;
y_pred =Time_series_predict;

% 计算误差指标
metrics.MSE = mean((y_test - y_pred).^2);
metrics.MAE = mean(abs(y_test - y_pred));
metrics.RMSE = sqrt(metrics.MSE);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            
        [file, path] = uigetfile('*.xlsx', 'Select Excel File');
            if isequal(file, 0)
                return; % 用户取消选择
            end
            
            % 读取文件
            filePath = fullfile(path, file);
            data = readtable(filePath);
            
            % 假设时间序列在第一列和第二列
            time = data{:, 1};
            Time_series = data{:, 2};

            app.DataTable = table(time, Time_series);
            
%             Time_point_series = app.DataTable;
            
            % 绘制时间序列
            plot(app.UIAxes, time, Time_series, '-o');
            title(app.UIAxes, 'Time Series');
            xlabel(app.UIAxes, 'Time point');
            ylabel(app.UIAxes, 'Value');
            legend(app.UIAxes, 'Original data');
            
            % 动态调整范围
            app.UIAxes.XLim = [min(time), max(time) + 1];  % X 轴范围
            app.UIAxes.YLim = [min(Time_series) - 2, max(Time_series) + 2];  % Y 轴范围
            
            T_e=length(Time_series);
            % 设置 X 轴刻度和标签
            app.UIAxes.XTick = [1 T_e];
            app.UIAxes.XTickLabel = {'1', 'T_e'};
            
%             length(values)
            
            app.T_0Slider.Limits = [0 length(Time_series)];


        end

        % Value changed function: T_0Slider
        function T_0SliderValueChanged(app, event)

%             T_0 = app.T_0Slider.Value;
%             T_0=int32(T_0);
            
            T_0 = round(app.T_0Slider.Value);  % 四舍五入为整数
            
            cla(app.UIAxes);  % 清除指定坐标轴 
            
            time = app.DataTable{:, 1};
            Time_series = app.DataTable{:, 2};
            
            T_e=length(Time_series);
            % 打开 hold 功能
            hold(app.UIAxes, 'on');
            
            % 绘制时间序列
           
            
            % 原始的时间序列   
            plot(app.UIAxes, time,Time_series, '-o');
            title(app.UIAxes, 'Time Series');
            xlabel(app.UIAxes, 'Time point');
            ylabel(app.UIAxes, 'Value');
            
             % 绘制多条曲线
            plot(app.UIAxes,[T_0,T_0],[min(Time_series) - 2,max(Time_series) + 2],...
                'k-.','linewidth',2.5);
            
%             legend(app.UIAxes, 'Original data');
            % 动态调整范围
            app.UIAxes.XLim = [min(time), max(time) + 1];  % X 轴范围
            app.UIAxes.YLim = [min(Time_series) - 2, max(Time_series) + 2];  % Y 轴范围
            
            % 设置 X 轴刻度和标签
            app.UIAxes.XTick = [1 T_0 T_e];
            app.UIAxes.XTickLabel = {'1', 'Nominal time', 'T_e'};
%             set(app.UIAxes,gca,'xtick',[1 T_0 T_e],'xticklabel',{'1' 'T_0' 'T_e'})%2007/7/1
            legend(app.UIAxes, {'Original Data', 'Nominal time line'});
            
            % 关闭 hold 功能（可选）
            hold(app.UIAxes, 'off');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 滑动下面窗口
            l1 = round(app.l1Slider.Value);
            l2 = round(app.l2Slider.Value);
            
            T_0 = round(app.T_0Slider.Value);  % 四舍五入为整数
            
            Time_series = app.DataTable{:, 2};
            T_e=length(Time_series);
            
            
            [Structure_out,Structure]=Lag_DF_app(app,T_0,T_e,l1,l2);
            
        end

        % Callback function
        function l1DropDownValueChanged(app, event)


%             T_0=48;


        end

        % Callback function
        function l2DropDownValueChanged(app, event)
%             l2 = app.l2DropDown.Value;
%             l2=str2double(l2);
%             
            
        end

        % Value changed function: AR_OrderDropDown
        function AR_OrderDropDownValueChanged(app, event)
            
            
            AR_Order = round(app.AR_OrderDropDown.Value);
            
            
        end

        % Value changed function: Lag_ordersDropDown
        function Lag_ordersDropDownValueChanged(app, event)
%             lag_order_s = app.Lag_ordersDropDown.Value;
%             lag_order_s=str2double(lag_order_s);
            
            lag_order_s = round(app.Lag_ordersDropDown.Value);
            
        end

        % Value changed function: l1Slider
        function l1SliderValueChanged(app, event)

        
            l1 = round(app.l1Slider.Value);
            l2 = round(app.l2Slider.Value);
            
%             T_0=48;
            T_0 = round(app.T_0Slider.Value);  % 四舍五入为整数
            
            Time_series = app.DataTable{:, 2};
            T_e=length(Time_series);
            
            
            [Structure_out,Structure]=Lag_DF_app(app,T_0,T_e,l1,l2);
            
        end

        % Value changed function: l2Slider
        function l2SliderValueChanged(app, event)

            
            l1 = round(app.l1Slider.Value);
            l2 = round(app.l2Slider.Value);
            
%             T_0=48;
            T_0 = round(app.T_0Slider.Value);  % 四舍五入为整数
            
            Time_series = app.DataTable{:, 2};
            T_e=length(Time_series);
            
            
            [Structure_out,Structure]=Lag_DF_app(app,T_0,T_e,l1,l2);
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        % 获取参数
        Time_series = app.DataTable{:, 2};
        
        T_0 = round(app.T_0Slider.Value);  % 四舍五入为整数
        T_e=length(Time_series);
        
        l1 = round(app.l1Slider.Value);
        l2 = round(app.l2Slider.Value);
        
%         str2double(app.RowEditField.Value)
        
        AR_Order = str2double(app.AR_OrderDropDown.Value);
        lag_order_s = str2double(app.Lag_ordersDropDown.Value);
            
%         [Structure_out,Structure]=Lag_DF_senario(T_0,T_e,Advance_length,Lag_length);
% 
%         [Time_series_predict,LagEffect,Beta_LagTerm]=Parameter_estimation(T_0,T_e,Advance_length,Lag_length,Time_series,Structure_out(:,1:5),AR_Order,s);
        
        [Structure_out,Structure]=Lag_DF_app(app,T_0,T_e,l1,l2);
        
        [Time_series_predict,LagEffect,Beta_LagTerm]=Parameter_estimation(app,T_0,T_e,l1,l2,Time_series,Structure_out(:,1:5),AR_Order,lag_order_s);


        % 切换到 Tab2
        app.TabGroup.SelectedTab = app.ResultoutputTab;


        end

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.ButtonPushed.Value;
            
            lag_order_s = round(app.Lag_ordersDropDown.Value);
            class(lag_order_s)
            app.ButtonPushed.Text = str2;
            
        end

        % Button pushed function: OutputresultButton
        function OutputresultButtonPushed(app, event)
            % 获取参数
        Time_series = app.DataTable{:, 2};
        
        T_0 = round(app.T_0Slider.Value);  % 四舍五入为整数
        T_e=length(Time_series);
        
        l1 = round(app.l1Slider.Value);
        l2 = round(app.l2Slider.Value);
        
%         str2double(app.RowEditField.Value)
        
        AR_Order = str2double(app.AR_OrderDropDown.Value);
        lag_order_s = str2double(app.Lag_ordersDropDown.Value);
             
        [Structure_out,Structure]=Lag_DF_app(app,T_0,T_e,l1,l2);
        
        [app.Time_series_predict,app.LagEffect,app.Beta_LagTerm]=Parameter_estimation(app,T_0,T_e,l1,l2,Time_series,Structure_out(:,1:5),AR_Order,lag_order_s);

%         app.Time_series_predict=
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 检查数据是否已生成
    if isempty(app.Time_series_predict) || isempty(app.LagEffect) || isempty(app.Beta_LagTerm)
        uialert(app.UIFigure, 'No data available to save. Please run the model first.', 'Error');
        return;
    end
    
    % 弹出文件保存对话框
    [fileName, filePath] = uiputfile({'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                                     'Save Results as Excel File');
    
    % 检查用户是否取消操作
    if isequal(fileName, 0) || isequal(filePath, 0)
        % 用户取消保存，显示消息并退出回调
        uialert(app.UIFigure, 'Save operation cancelled.', 'Cancelled');
        return;
    end
    
    % 拼接完整路径
    fullFileName = fullfile(filePath, fileName);
    
    % 尝试保存数据到 Excel
    try
        % 保存 Time_series_predict 到 Sheet1
        writematrix(app.Time_series_predict, fullFileName, 'Sheet', 'Time_Series_Predict');
        
        % 保存 LagEffect 到 Sheet2
        writematrix(app.LagEffect, fullFileName, 'Sheet', 'Lag_Effect');
        
        % 保存 Beta_LagTerm 到 Sheet3
        writematrix(app.Beta_LagTerm, fullFileName, 'Sheet', 'Beta_LagTerm');
        
        % 保存成功提示
        uialert(app.UIFigure, ['Results saved successfully to: ', fullFileName], 'Success');
    catch ME
        % 保存失败提示
        uialert(app.UIFigure, ['Error saving file: ', ME.message], 'Error');
    end


        end

        % Callback function
        function ButtonPushedPushed(app, event)
            % 创建 Java Swing 文件选择器
    jFileChooser = javax.swing.JFileChooser;
    
    % 设置默认目录 (可选)
    jFileChooser.setCurrentDirectory(java.io.File(pwd));
    
    % 显示文件选择对话框（0 = 选择成功，1 = 取消）
    status = jFileChooser.showOpenDialog([]);
    
    if status == javax.swing.JFileChooser.APPROVE_OPTION
        % 获取选择的文件路径
        selectedFile = char(jFileChooser.getSelectedFile().getAbsolutePath);
        
        % 在 App Designer 的 EditField 显示文件路径 (如果有)
        app.FilePathEditField.Value = selectedFile;
        
        % 在命令行显示文件路径（调试用）
        disp(['选中文件: ', selectedFile]);
    else
        disp('用户取消了文件选择');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersettingsTab = uitab(app.TabGroup);
            app.ParametersettingsTab.Title = 'Parameter settings';
            app.UIAxes = uiaxes(app.ParametersettingsTab);
            title(app.UIAxes, 'Time series')
            xlabel(app.UIAxes, 'time point')
            ylabel(app.UIAxes, 'Value')
            app.T_0SliderLabel = uilabel(app.ParametersettingsTab);
            app.T_0SliderLabel.Text = 'T_0';
            app.T_0Slider = uislider(app.ParametersettingsTab);
            app.T_0Slider.ValueChangedFcn = createCallbackFcn(app, @T_0SliderValueChanged, true);
            app.AR_OrderDropDownLabel = uilabel(app.ParametersettingsTab);
            app.AR_OrderDropDownLabel.Text = 'AR_Order';
            app.AR_OrderDropDown = uidropdown(app.ParametersettingsTab);
            app.AR_OrderDropDown.Items = {'1', '2', '3', '4'};
            app.AR_OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_OrderDropDownValueChanged, true);
            app.AR_OrderDropDown.Value = '2';
            app.Lag_ordersDropDownLabel = uilabel(app.ParametersettingsTab);
            app.Lag_ordersDropDownLabel.Text = 'Lag_order-s';
            app.Lag_ordersDropDown = uidropdown(app.ParametersettingsTab);
            app.Lag_ordersDropDown.Items = {'6', '8', '10', '12', '14'};
            app.Lag_ordersDropDown.ValueChangedFcn = createCallbackFcn(app, @Lag_ordersDropDownValueChanged, true);
            app.Lag_ordersDropDown.Value = '10';
            app.ImportDataButton = uibutton(app.ParametersettingsTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.RunButton = uibutton(app.ParametersettingsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.l1SliderLabel = uilabel(app.ParametersettingsTab);
            app.l1SliderLabel.Text = 'l1';
            app.l1Slider = uislider(app.ParametersettingsTab);
            app.l1Slider.Limits = [1 10];
            app.l1Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.l1Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.l1Slider.ValueChangedFcn = createCallbackFcn(app, @l1SliderValueChanged, true);
            app.l1Slider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.l1Slider.Value = 6;
            app.UIAxes4 = uiaxes(app.ParametersettingsTab);
            title(app.UIAxes4, 'Parameter setting scenario')
            xlabel(app.UIAxes4, 'time point')
            ylabel(app.UIAxes4, 'Effect size')
            app.l2SliderLabel = uilabel(app.ParametersettingsTab);
            app.l2SliderLabel.Text = 'l2';
            app.l2Slider = uislider(app.ParametersettingsTab);
            app.l2Slider.Limits = [1 10];
            app.l2Slider.ValueChangedFcn = createCallbackFcn(app, @l2SliderValueChanged, true);
            app.l2Slider.Value = 6;
            app.Step4LampLabel = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel.Text = 'Step 1';
            app.Step4LampLabel_2 = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel_2.Text = 'Step 3';
            app.Step4LampLabel_3 = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel_3.Text = 'Last Step';
            app.Step4LampLabel_4 = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel_4.Text = 'Step 4';
            app.Step4LampLabel_5 = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel_5.Text = 'Step 5';
            app.Step4LampLabel_6 = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel_6.Text = 'Step 6';
            app.Step4LampLabel_7 = uilabel(app.ParametersettingsTab);
            app.Step4LampLabel_7.Text = 'Step 2';
            app.ResultoutputTab = uitab(app.TabGroup);
            app.ResultoutputTab.Title = 'Result output';
            app.UIAxes3 = uiaxes(app.ResultoutputTab);
            title(app.UIAxes3, 'UD')
            xlabel(app.UIAxes3, 'time point')
            ylabel(app.UIAxes3, 'Intervention Effect size')
            app.UIAxes3_2 = uiaxes(app.ResultoutputTab);
            title(app.UIAxes3_2, 'ND')
            xlabel(app.UIAxes3_2, 'time point')
            ylabel(app.UIAxes3_2, 'Intervention Effect size')
            app.UIAxes3_3 = uiaxes(app.ResultoutputTab);
            title(app.UIAxes3_3, 'LND')
            xlabel(app.UIAxes3_3, 'time point')
            ylabel(app.UIAxes3_3, 'Intervention Effect size')
            app.UIAxes3_4 = uiaxes(app.ResultoutputTab);
            title(app.UIAxes3_4, 'LNFD')
            xlabel(app.UIAxes3_4, 'time point')
            ylabel(app.UIAxes3_4, 'Intervention Effect size')
            app.OutputresultButton = uibutton(app.ResultoutputTab, 'push');
            app.OutputresultButton.ButtonPushedFcn = createCallbackFcn(app, @OutputresultButtonPushed, true);
            app.OutputresultButton.Text = 'Output result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Newmodel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end